classdef AUTOSAS_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        Menu_3              matlab.ui.container.Menu
        Menu_2              matlab.ui.container.Menu
        Image2              matlab.ui.control.Image
        TextArea_3          matlab.ui.control.TextArea
        TabGroup            matlab.ui.container.TabGroup
        CANTab              matlab.ui.container.Tab
        EditField_19        matlab.ui.control.EditField
        Label_31            matlab.ui.control.Label
        IDEditField_5       matlab.ui.control.EditField
        IDEditField_5Label  matlab.ui.control.Label
        DropDown_8          matlab.ui.control.DropDown
        Label_28            matlab.ui.control.Label
        DropDown_6          matlab.ui.control.DropDown
        Label_22            matlab.ui.control.Label
        Button_12           matlab.ui.control.Button
        EditField_11        matlab.ui.control.EditField
        Label_17            matlab.ui.control.Label
        Button_11           matlab.ui.control.Button
        UITable4            matlab.ui.control.Table
        IDEditField_3       matlab.ui.control.NumericEditField
        IDEditField_3Label  matlab.ui.control.Label
        IDButton_2          matlab.ui.control.Button
        EditField_10        matlab.ui.control.EditField
        Label_16            matlab.ui.control.Label
        Button_10           matlab.ui.control.Button
        CANFDTab            matlab.ui.container.Tab
        EditField_20        matlab.ui.control.EditField
        Label_30            matlab.ui.control.Label
        IDEditField_6       matlab.ui.control.EditField
        IDLabel_2           matlab.ui.control.Label
        DropDown_9          matlab.ui.control.DropDown
        Label_29            matlab.ui.control.Label
        DropDown_5          matlab.ui.control.DropDown
        Label_21            matlab.ui.control.Label
        Button_16           matlab.ui.control.Button
        EditField_14        matlab.ui.control.EditField
        EditField_14Label   matlab.ui.control.Label
        Button_15           matlab.ui.control.Button
        UITable5            matlab.ui.control.Table
        IDEditField         matlab.ui.control.NumericEditField
        IDEditFieldLabel    matlab.ui.control.Label
        IDButton            matlab.ui.control.Button
        EditField_13        matlab.ui.control.EditField
        Label_20            matlab.ui.control.Label
        CADFDButton         matlab.ui.control.Button
        Tab_5               matlab.ui.container.Tab
        EditField_12        matlab.ui.control.EditField
        Label_19            matlab.ui.control.Label
        Button_14           matlab.ui.control.Button
        Button_13           matlab.ui.control.Button
        Tab_3               matlab.ui.container.Tab
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CAN_ID_cell = struct(); 
        CAN_id_amount;
        CAN_A;
        CANFD_ID_Cell = struct();
        CANFD_id_amount;
        CANFD_A;
        ID;
    end
    
    methods (Access = public)
    end
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="AUTO SAS";
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            choice = questdlg('是否退出程序','退出对话框','是','否','否');
            switch choice
                case '是'
                    delete(app.UIFigure);
                    return;
                case '否'
                    return;
            end
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            msgbox('这是一个信号解析的小程序...欢迎就信号解析技术进行探讨','关于','help');
        end

        % Callback function
        function ButtonPushed(app, event)
            [file,path] = uigetfile('.asc','选择文件');
            filename = [path,file];
            app.EditField.Value = filename;
            msgbox("文件路径选择成功","提示");
        end

        % Callback function
        function Button_2Pushed(app, event)

            opt_int_id = eval(app.IDEditField_2.Value);
            opt_int_moto = app.DropDown.Value;
            range_bit = app.EditField_15.Value;
            start_bit = app.EditField_16.Value;
            fig_save = app.EditField_2.Value;
            signal_judge = app.DropDown_7.Value;
            factor = app.EditField_17.Value;
            offset = app.EditField_18.Value;
            fig_id = opt_int_id.id(1);
            table_bl_dbc = table([7 6 5 4 3 2 1 0 15 14 13 12 11 10 9 8 23 22 21 20 19 18 17 16 31 30 29 28 27 26 25 24 39 38 37 36 35 34 33 32 47 46 45 44 43 42 41 40 55 54 53 52 51 50 49 48 63 62 61 60 59 58 57 56 ]',[1:64]','VariableNames',{'dbc_Sta','bl_sta',});
            switch opt_int_moto
                case 'Intel'
                    opt_int_id.x09= [opt_int_id.x08,opt_int_id.x07,opt_int_id.x06,opt_int_id.x05,opt_int_id.x04,opt_int_id.x03,opt_int_id.x02,opt_int_id.x01];
                    bianli_bit = 64-start_bit;
                    a = opt_int_id{:,12};
                    b = a(:,bianli_bit-range_bit+1:bianli_bit);
                    size_b =size(b);
                    ku = size_b(2);
                    size_b_2 = size_b(1,1);
                    q = 1;
                    while q < size_b_2
                        judge_b = strcmp(b(q,:),b(q+1,:));
                        q = q+1; 
                        if judge_b == 0
                            x = opt_int_id.time;
                            if signal_judge == "unsigned"
                                y = (bin2dec(b)*factor)+offset;
                            elseif signal_judge == "signed"
                                signed_bit = b(:,1);
                                for p = 1:size_b_2
                                    if signed_bit(p) == '0'
                                        opt_int_id.x10(p,:) = bin2dec(b(p,:));
                                    elseif signed_bit(p) == '1'
                                        opt_int_id.x10(p,:) = (2^ku - bin2dec(b(p,:)))*-1;
                                    else
                                        return
                                    end
                                end
                                y = (opt_int_id.x10*factor)+offset;
                            end
                            plot(app.UIAxes,x,y);
                            app.UIAxes.Title.String = sprintf('ID是%s；起始位是%s；信号位数是%s',fig_id,num2str(start_bit),num2str(range_bit));
                            exportgraphics(app.UIAxes,[fig_save,sprintf('ID%s_%s_%s',fig_id,num2str(start_bit),num2str(range_bit)),'.jpg']);
                            close;
                            break
                        elseif q == size_b_2
                            break
                        end
                    end
                case 'Motorola'
                    opt_int_id.x09= [opt_int_id.x01,opt_int_id.x02,opt_int_id.x03,opt_int_id.x04,opt_int_id.x05,opt_int_id.x06,opt_int_id.x07,opt_int_id.x08];
                    bl_row = table_bl_dbc.dbc_Sta(:) == start_bit;
                    bl_index = find(bl_row);
                    bl_num = table_bl_dbc.bl_sta(bl_index);
                    a = opt_int_id{:,12};
                    b = a(:,bl_num-range_bit+1:bl_num);
                    size_b =size(b);
                    ku = size_b(2);
                    size_b_2 = size_b(1,1);
                    q = 1;
                    while q < size_b_2
                        judge_b = strcmp(b(q,:),b(q+1,:));
                        q = q+1;
                        if judge_b == 0
                            x = opt_int_id.time;
                            if signal_judge == "unsigned"
                                y = (bin2dec(b)*factor)+offset;
                            elseif signal_judge == "signed"
                                signed_bit = b(:,1);
                                for p = 1:size_b_2
                                    if signed_bit(p) == '0'
                                        opt_int_id.x10(p,:) = bin2dec(b(p,:));
                                    elseif signed_bit(p) == '1'
                                        opt_int_id.x10(p,:) = (2^ku - bin2dec(b(p,:)))*-1;
                                    else
                                        return
                                    end
                                end
                                y = (opt_int_id.x10*factor)+offset;
                            end
                            plot(app.UIAxes,x,y);
                            app.UIAxes.Title.String = sprintf('ID是%s；起始位是%s；信号位数是%s',fig_id,num2str(start_bit),num2str(range_bit));
                            exportgraphics(app.UIAxes,[fig_save,sprintf('ID%s_%s_%s',fig_id,num2str(start_bit),num2str(range_bit)),'.jpg']);
                            close;
                            break
                        elseif q == size_b_2
                            break
                        end
                    end         
                otherwise
                    disp('你的输入不符合要求，请按要求输入：');
            end
            msgbox("ID_位数_起始位画图显示完成","提示");
        end

        % Callback function
        function Button_3Pushed(app, event)
            save_dir = uigetdir();
            save_dir_2 = [save_dir,'\'];
            app.EditField_2.Value = save_dir_2;
            msgbox("存储路径选择完成","提示");
        end

        % Callback function
        function IDButton_3Pushed(app, event)
            filename = app.EditField.Value;
            ID = importfileasc(app,filename);
            % importfileasc(input('请输入目标asc文件名(包含文件类型后缀）：','s'))

            %% 表格预处理、删除前四行、删除错误行、删除多余列、生成新的表格。
            % 删除前四行  注意变量的使用，不要重复了。删除行delrow。
            delrow = 1;
            a = 1;
            while a<5
                ID(delrow,:)=[];
                a = a+1;
            end
            % message([1:4],:) = [];这条语句和上面功能一样，

            % 删除多余列    仅仅保留time id digit(位数) x01\x02\x03
            ID.Wed = [];
            ID.VarName4 = [];
            ID.VarName5 = [];
            ID.VarName15 = [];
            ID.VarName16 = [];
            ID.VarName17 = [];
            ID.VarName18 = [];
            ID.VarName19 = [];
            ID.VarName20 = [];
            ID.VarName21 = [];
            ID.VarName22 = [];
            ID.VarName23 = [];

            % 删除错误行  x01 = 'active'、
            del_row1 = ID.x01(:) == 'active';
            ID(del_row1,:) = [];

            % 删除错误行， id = 'Statistic'
            del_row2 = ID.id(:) == 'Statistic:';
            ID(del_row2,:) = [];

            % 删除错误行，x02 = '='
            del_row10 = ID.x02(:) == '=';
            ID(del_row10,:) = [];
            % 删除错误信息，并用‘00’代替 x02
            del_row3 = ID.x02(:) == 'Length';
            del_index3 = find(del_row3);
            ID.x02(del_index3) = "00";
            ID.x03(del_index3) = "00";
            ID.x04(del_index3) = "00";
            ID.x05(del_index3) = "00";
            ID.x06(del_index3) = "00";
            ID.x07(del_index3) = "00";
            ID.x08(del_index3) = "00";

            % 删除错误信息，并用‘00’代替 x03
            del_row4 = ID.x03(:) == 'Length';
            del_index4 = find(del_row4);
            ID.x03(del_index4) = "00";
            ID.x04(del_index4) = "00";
            ID.x05(del_index4) = "00";
            ID.x06(del_index4) = "00";
            ID.x07(del_index4) = "00";
            ID.x08(del_index4) = "00";


            % 删除错误信息，并用‘00’代替 x04
            del_row5 = ID.x04(:) == 'Length';
            del_index5 = find(del_row5);
            ID.x04(del_index5) = "00";
            ID.x05(del_index5) = "00";
            ID.x06(del_index5) = "00";
            ID.x07(del_index5) = "00";
            ID.x08(del_index5) = "00";

            % 删除错误信息，并用‘00’代替 x05
            del_row6 = ID.x05(:) == 'Length';
            del_index6 = find(del_row6);
            ID.x05(del_index6) = "00";
            ID.x06(del_index6) = "00";
            ID.x07(del_index6) = "00";
            ID.x08(del_index6) = "00";

            % 删除错误信息，并用‘00’代替 x06
            del_row7 = ID.x06(:) == 'Length';
            del_index7 = find(del_row7);
            ID.x06(del_index7) = "00";
            ID.x07(del_index7) = "00";
            ID.x08(del_index7) = "00";

            % 删除错误信息，并用‘00’代替 x07
            del_row8 = ID.x07(:) == 'Length';
            del_index8 = find(del_row8);
            ID.x07(del_index8) = "00";
            ID.x08(del_index8) = "00";

            % 删除错误信息，并用‘00’代替 x08
            del_row9 = ID.x08(:) == 'Length';
            del_index9 = find(del_row9);
            ID.x08(del_index9) = "00";

            % 报文转换成二进制报文
            ID.x01 = dec2bin(hex2dec(ID.x01(:)));
            ID.x02 = dec2bin(hex2dec(ID.x02(:)));
            ID.x03 = dec2bin(hex2dec(ID.x03(:)));
            ID.x04 = dec2bin(hex2dec(ID.x04(:)));
            ID.x05 = dec2bin(hex2dec(ID.x05(:)));
            ID.x06 = dec2bin(hex2dec(ID.x06(:)));
            ID.x07 = dec2bin(hex2dec(ID.x07(:)));
            ID.x08 = dec2bin(hex2dec(ID.x08(:)));

            %% 对表格进行排序
            ID = sortrows(ID,2);   %依照id列对table进行排序

            %% 计算出来总共有多少个变量，一个数据里面id数量确认
            ID1 = ID;  %这个变量用于测试出这个数据里面又多少个id
            row_column_table1 = size(ID1);
            row_table1 = row_column_table1(1);
            id_amount = 0;
            while row_table1 > 0 %程序退出判断当message的row为0退出循环,退出循环
                id_amount = id_amount + 1;
                %动态变量删除和生成新的表代码
                del_norm1 = ID1.id(1);   % del_norm 删除选择基准变量
                p = 2;
                while ID1.id(p)== del_norm1
                    p = p+1;
                    if p > row_table1
                        break
                    end
                end
                ID1(1:p-1,:)=[];
                row_column_table1 = size(ID1);
                row_table1 = row_column_table1(1);
            end
            %% 清除测试中用到的多余变量
            clear ID1;
            clear p;
            clear row_column_table1;
            clear row_table1;

            %% 对排序后的表格进行提取依照id创建多个子表格
            row_column_table = size(ID);
            row_table = row_column_table(1);
            A = table('Size',[id_amount 1],'VariableNames',{'id'},'VariableTypes',{'string'}) ;
            idx = 0;
            while idx < id_amount    %程序退出判断当message的row为0退出循环,退出循环
                %动态变量删除和生成新的表代码
                idx = idx +1;
                del_norm = ID.id(1);   % del_norm 删除选择基准变量
                n = 2;
                while ID.id(n)== del_norm
                    n = n+1;
                    if n > row_table
                        break
                    end
                end
                b = ['ID_',char(del_norm)];%这里就是一个简单的字符串的拼接
                A.id(idx)=b;
                k = ID(1:n-1,:);
                eval([b,'=k']);         %这个eval函数非常不利于代码的阅读，考虑后面替换这几行代码
                ID(1:n-1,:)=[];
                row_column_table = size(ID);
                row_table = row_column_table(1);
            end
            %% 清楚多余变量
            clear_var = {'n','k','a'};
            clear (clear_var{:});
            clear -regexp ^del ^row;
            clear ID;
            app.IDEditField_4.Value = id_amount;
            app.UITable.Data = A;
            msgbox("id查询完成","提示");
        end

        % Callback function
        function EditField_2ValueChanged(app, event)
            %value = app.EditField_2.Value;
        end

        % Callback function
        function Button_4Pushed(app, event)
            [file1,path1] = uigetfile('.asc','选择文件');
            filename1 = [path1,file1];
            app.EditField_4.Value = filename1;
            msgbox("文件路径选择成功","提示");
        end

        % Callback function
        function idButton_2Pushed(app, event)
          
        end

        % Callback function
        function Button_5Pushed(app, event)
            save_dir1 = uigetdir();
            save_dir_3 = [save_dir1,'\'];
            app.EditField_5.Value = save_dir_3;
            msgbox("存储路径选择完成","提示");
        end

        % Callback function
        function Button_6Pushed(app, event)
            filename = app.EditField_4.Value;
            message = importfileasc(app,filename);
            % importfileasc(input('请输入目标asc文件名(包含文件类型后缀）：','s'))

            %% 表格预处理、删除前四行、删除错误行、删除多余列、生成新的表格。
            % 删除前四行  注意变量的使用，不要重复了。删除行delrow。
            delrow = 1;
            a = 1;
            while a<5
                message(delrow,:)=[];
                a = a+1;
            end
            % message([1:4],:) = [];这条语句和上面功能一样，

            % 删除多余列    仅仅保留time id digit(位数) x01\x02\x03
            message.Wed = [];
            message.VarName4 = [];
            message.VarName5 = [];
            message.VarName15 = [];
            message.VarName16 = [];
            message.VarName17 = [];
            message.VarName18 = [];
            message.VarName19 = [];
            message.VarName20 = [];
            message.VarName21 = [];
            message.VarName22 = [];
            message.VarName23 = [];

            % 删除错误行  x01 = 'active'、
            del_row1 = message.x01(:) == 'active';
            %del_index1 = find(del_row1);
            message(del_row1,:) = [];

            % 删除错误行， id = 'Statistic'
            del_row2 = message.id(:) == 'Statistic:';
            %del_index2 = find(del_row2);
            message(del_row2,:) = [];

            % 删除错误行，x02 = '='
            del_row10 = message.x02(:) == '=';
            %del_index10 = find(del_row10);
            message(del_row10,:) = [];
            del_row3 = message.x02(:) == 'Length';
            del_index3 = find(del_row3);
            message.x02(del_index3) = "00";
            message.x03(del_index3) = "00";
            message.x04(del_index3) = "00";
            message.x05(del_index3) = "00";
            message.x06(del_index3) = "00";
            message.x07(del_index3) = "00";
            message.x08(del_index3) = "00";

            % 删除错误信息，并用‘00’代替 x03
            del_row4 = message.x03(:) == 'Length';
            del_index4 = find(del_row4);
            message.x03(del_index4) = "00";
            message.x04(del_index4) = "00";
            message.x05(del_index4) = "00";
            message.x06(del_index4) = "00";
            message.x07(del_index4) = "00";
            message.x08(del_index4) = "00";


            % 删除错误信息，并用‘00’代替 x04
            del_row5 = message.x04(:) == 'Length';
            del_index5 = find(del_row5);
            message.x04(del_index5) = "00";
            message.x05(del_index5) = "00";
            message.x06(del_index5) = "00";
            message.x07(del_index5) = "00";
            message.x08(del_index5) = "00";

            % 删除错误信息，并用‘00’代替 x05
            del_row6 = message.x05(:) == 'Length';
            del_index6 = find(del_row6);
            message.x05(del_index6) = "00";
            message.x06(del_index6) = "00";
            message.x07(del_index6) = "00";
            message.x08(del_index6) = "00";

            % 删除错误信息，并用‘00’代替 x06
            del_row7 = message.x06(:) == 'Length';
            del_index7 = find(del_row7);
            message.x06(del_index7) = "00";
            message.x07(del_index7) = "00";
            message.x08(del_index7) = "00";

            % 删除错误信息，并用‘00’代替 x07
            del_row8 = message.x07(:) == 'Length';
            del_index8 = find(del_row8);
            message.x07(del_index8) = "00";
            message.x08(del_index8) = "00";

            % 删除错误信息，并用‘00’代替 x08
            del_row9 = message.x08(:) == 'Length';
            del_index9 = find(del_row9);
            message.x08(del_index9) = "00";

            % 报文转换成二进制报文
            message.x01 = dec2bin(hex2dec(message.x01(:)));
            message.x02 = dec2bin(hex2dec(message.x02(:)));
            message.x03 = dec2bin(hex2dec(message.x03(:)));
            message.x04 = dec2bin(hex2dec(message.x04(:)));
            message.x05 = dec2bin(hex2dec(message.x05(:)));
            message.x06 = dec2bin(hex2dec(message.x06(:)));
            message.x07 = dec2bin(hex2dec(message.x07(:)));
            message.x08 = dec2bin(hex2dec(message.x08(:)));

            %% 对表格进行排序
            message = sortrows(message,2);   %依照id列对table进行排序

            %% 计算出来总共有多少个变量，一个数据里面id数量确认
            message1 = message;  %这个变量用于测试出这个数据里面又多少个id
            row_column_table1 = size(message1);
            row_table1 = row_column_table1(1);
            id_amount = 0;
            while row_table1 > 0 %程序退出判断当message的row为0退出循环,退出循环
                id_amount = id_amount + 1;
                %动态变量删除和生成新的表代码
                del_norm1 = message1.id(1);   % del_norm 删除选择基准变量
                p = 2;
                while message1.id(p)== del_norm1
                    p = p+1;
                    if p > row_table1
                        break
                    end
                end
                message1(1:p-1,:)=[];
                row_column_table1 = size(message1);
                row_table1 = row_column_table1(1);
            end
            %% 清除测试中用到的多余变量
            clear message1;
            clear p;
            clear row_column_table1;
            clear row_table1;

            %% 对排序后的表格进行提取依照id创建多个子表格
            row_column_table = size(message);
            row_table = row_column_table(1);
            A = table('Size',[id_amount 1],'VariableNames',{'id'},'VariableTypes',{'string'}) ;
            idx = 0;
            while idx < id_amount    %程序退出判断当message的row为0退出循环,退出循环
                %动态变量删除和生成新的表代码
                idx = idx +1;
                del_norm = message.id(1);   % del_norm 删除选择基准变量
                n = 2;
                while message.id(n)== del_norm
                    n = n+1;
                    if n > row_table
                        break
                    end
                end
                b = ['message_',char(del_norm)];%这里就是一个简单的字符串的拼接
                A.id(idx)=b;
                k = message(1:n-1,:);
                eval([b,'=k']);         %这个eval函数非常不利于代码的阅读，考虑后面替换这几行代码
                message(1:n-1,:)=[];
                row_column_table = size(message);
                row_table = row_column_table(1);
            end
            %% 清楚多余变量
            clear_var = {'n','k','a'};
            clear (clear_var{:});
            clear -regexp ^del ^row;
            clear message;
            %% 特定位数，遍历所有id 然后将图片放在特定文件夹里面。
            opt_int_moto = app.DropDown_2.Value;
            range_bit = app.EditField_6.Value;
            fig_str2 = app.EditField_5.Value;
            for i = 1:id_amount
                P = A.id(i);
                opt_int_id = eval(P);
                fig_id = opt_int_id.id(1);
                table_bl_dbc = table([7 6 5 4 3 2 1 0 15 14 13 12 11 10 9 8 23 22 21 20 19 18 17 16 31 30 29 28 27 26 25 24 39 38 37 36 35 34 33 32 47 46 45 44 43 42 41 40 55 54 53 52 51 50 49 48 63 62 61 60 59 58 57 56 ]',[1:64]','VariableNames',{'dbc_Sta','bl_sta',});
                %switch语句
                switch opt_int_moto
                    case 'Intel'
                        opt_int_id.x09= [opt_int_id.x08,opt_int_id.x07,opt_int_id.x06,opt_int_id.x05,opt_int_id.x04,opt_int_id.x03,opt_int_id.x02,opt_int_id.x01];
                        %       start_bit = 64-input('请输入起始位（范围0-63）：');
                        for start_bit = range_bit:64
                            a = opt_int_id{:,12};
                            b = a(:,start_bit-range_bit+1:start_bit);
                            %figure('name',fig_id);
                            size_b =size(b);
                            size_b_2 = size_b(1,1);
                            q = 1;
                            while q < size_b_2
                                judge_b = strcmp(b(q,:),b(q+1,:));
                                q = q+1;
                                if judge_b == 0
                                    x = opt_int_id.time;
                                    y = bin2dec(b);
                                    plot(app.UIAxes,x,y);
                                    app.UIAxes.Title.String = sprintf('ID是%s；起始位是%s；信号位数是%s',fig_id,num2str(64-start_bit),num2str(range_bit));
                                    exportgraphics(app.UIAxes,[fig_str2,sprintf('ID%s_%s_%s',fig_id,num2str(64-start_bit),num2str(range_bit)),'.jpg']);
                                    %title(sprintf('ID是%s；起始位是%s；信号位数是%s',fig_id,num2str(64-start_bit),num2str(range_bit)));
                                    %saveas(gcf,['D:\Program Files\Polyspace\R2021a\bin\code\fig_save\',sprintf('ID%s_%s_%s',fig_id,num2str(64-start_bit),num2str(range_bit))],'jpg');
                                    close;
                                    break
                                elseif q == size_b_2
                                    break
                                end
                            end
                        end
                    case 'Motorola'
                        opt_int_id.x09= [opt_int_id.x01,opt_int_id.x02,opt_int_id.x03,opt_int_id.x04,opt_int_id.x05,opt_int_id.x06,opt_int_id.x07,opt_int_id.x08];
                        for start_bit_m = range_bit:64
                            bl_row = table_bl_dbc.bl_sta(:) == start_bit_m;
                            bl_index = find(bl_row);
                            dbc_num = table_bl_dbc.dbc_Sta(bl_index);
                            a = opt_int_id{:,12};
                            b = a(:,start_bit_m-range_bit+1:start_bit_m);
                            size_b =size(b);
                            size_b_2 = size_b(1,1);
                            q = 1;
                            while q < size_b_2
                                judge_b = strcmp(b(q,:),b(q+1,:));
                                q = q+1;
                                if judge_b == 0
                                    x = opt_int_id.time;
                                    y = bin2dec(b);
                                    plot(app.UIAxes,x,y);
                                    app.UIAxes.Title.String = sprintf('ID是%s；起始位是%s；信号位数是%s',fig_id,num2str(dbc_num),num2str(range_bit));
                                    exportgraphics(app.UIAxes,[fig_str2,sprintf('ID%s_%s_%s',fig_id,num2str(dbc_num),num2str(range_bit)),'.jpg']);
                                    close;
                                    break
                                elseif q == size_b_2
                                    break
                                end
                            end
                        end
                    otherwise
                        disp('你的输入不符合要求，请按要求输入：')
                end
            end
            msgbox("遍历完成","提示");
        end

        % Callback function
        function Button_7Pushed(app, event)
            [file2,path2] = uigetfile('.asc','选择文件');
            filename2 = [path2,file2];
            app.EditField_8.Value = filename2;
            msgbox("文件路径选择成功","提示");
        end

        % Callback function
        function idButton_3Pushed(app, event)
            filename3 = app.EditField_8.Value;
            message = importfileasc(app,filename3);
            % importfileasc(input('请输入目标asc文件名(包含文件类型后缀）：','s'))

            %% 表格预处理、删除前四行、删除错误行、删除多余列、生成新的表格。
            % 删除前四行  注意变量的使用，不要重复了。删除行delrow。
            delrow = 1;
            a = 1;
            while a<5
                message(delrow,:)=[];
                a = a+1;
            end
            % message([1:4],:) = [];这条语句和上面功能一样，

            % 删除多余列    仅仅保留time id digit(位数) x01\x02\x03
            message.Wed = [];
            message.VarName4 = [];
            message.VarName5 = [];
            message.VarName15 = [];
            message.VarName16 = [];
            message.VarName17 = [];
            message.VarName18 = [];
            message.VarName19 = [];
            message.VarName20 = [];
            message.VarName21 = [];
            message.VarName22 = [];
            message.VarName23 = [];

            % 删除错误行  x01 = 'active'、
            del_row1 = message.x01(:) == 'active';
            %del_index1 = find(del_row1);
            message(del_row1,:) = [];

            % 删除错误行， id = 'Statistic'
            del_row2 = message.id(:) == 'Statistic:';
            %del_index2 = find(del_row2);
            message(del_row2,:) = [];

            % 删除错误行，x02 = '='
            del_row10 = message.x02(:) == '=';
            %del_index10 = find(del_row10);
            message(del_row10,:) = [];
            del_row3 = message.x02(:) == 'Length';
            del_index3 = find(del_row3);
            message.x02(del_index3) = "00";
            message.x03(del_index3) = "00";
            message.x04(del_index3) = "00";
            message.x05(del_index3) = "00";
            message.x06(del_index3) = "00";
            message.x07(del_index3) = "00";
            message.x08(del_index3) = "00";

            % 删除错误信息，并用‘00’代替 x03
            del_row4 = message.x03(:) == 'Length';
            del_index4 = find(del_row4);
            message.x03(del_index4) = "00";
            message.x04(del_index4) = "00";
            message.x05(del_index4) = "00";
            message.x06(del_index4) = "00";
            message.x07(del_index4) = "00";
            message.x08(del_index4) = "00";


            % 删除错误信息，并用‘00’代替 x04
            del_row5 = message.x04(:) == 'Length';
            del_index5 = find(del_row5);
            message.x04(del_index5) = "00";
            message.x05(del_index5) = "00";
            message.x06(del_index5) = "00";
            message.x07(del_index5) = "00";
            message.x08(del_index5) = "00";

            % 删除错误信息，并用‘00’代替 x05
            del_row6 = message.x05(:) == 'Length';
            del_index6 = find(del_row6);
            message.x05(del_index6) = "00";
            message.x06(del_index6) = "00";
            message.x07(del_index6) = "00";
            message.x08(del_index6) = "00";

            % 删除错误信息，并用‘00’代替 x06
            del_row7 = message.x06(:) == 'Length';
            del_index7 = find(del_row7);
            message.x06(del_index7) = "00";
            message.x07(del_index7) = "00";
            message.x08(del_index7) = "00";

            % 删除错误信息，并用‘00’代替 x07
            del_row8 = message.x07(:) == 'Length';
            del_index8 = find(del_row8);
            message.x07(del_index8) = "00";
            message.x08(del_index8) = "00";

            % 删除错误信息，并用‘00’代替 x08
            del_row9 = message.x08(:) == 'Length';
            del_index9 = find(del_row9);
            message.x08(del_index9) = "00";

            % 报文转换成二进制报文
            message.x01 = dec2bin(hex2dec(message.x01(:)));
            message.x02 = dec2bin(hex2dec(message.x02(:)));
            message.x03 = dec2bin(hex2dec(message.x03(:)));
            message.x04 = dec2bin(hex2dec(message.x04(:)));
            message.x05 = dec2bin(hex2dec(message.x05(:)));
            message.x06 = dec2bin(hex2dec(message.x06(:)));
            message.x07 = dec2bin(hex2dec(message.x07(:)));
            message.x08 = dec2bin(hex2dec(message.x08(:)));

            %% 对表格进行排序
            message = sortrows(message,2);   %依照id列对table进行排序

            %% 计算出来总共有多少个变量，一个数据里面id数量确认
            message1 = message;  %这个变量用于测试出这个数据里面又多少个id
            row_column_table1 = size(message1);
            row_table1 = row_column_table1(1);
            id_amount = 0;
            while row_table1 > 0 %程序退出判断当message的row为0退出循环,退出循环
                id_amount = id_amount + 1;
                %动态变量删除和生成新的表代码
                del_norm1 = message1.id(1);   % del_norm 删除选择基准变量
                p = 2;
                while message1.id(p)== del_norm1
                    p = p+1;
                    if p > row_table1
                        break
                    end
                end
                message1(1:p-1,:)=[];
                row_column_table1 = size(message1);
                row_table1 = row_column_table1(1);
            end
            %% 清除测试中用到的多余变量
            clear message1;
            clear p;
            clear row_column_table1;
            clear row_table1;

            %% 对排序后的表格进行提取依照id创建多个子表格
            row_column_table = size(message);
            row_table = row_column_table(1);
            A = table('Size',[id_amount 1],'VariableNames',{'id'},'VariableTypes',{'string'}) ;
            idx = 0;
            while idx < id_amount    %程序退出判断当message的row为0退出循环,退出循环
                %动态变量删除和生成新的表代码
                idx = idx +1;
                del_norm = message.id(1);   % del_norm 删除选择基准变量
                n = 2;
                while message.id(n)== del_norm
                    n = n+1;
                    if n > row_table
                        break
                    end
                end
                b = ['message_',char(del_norm)];%这里就是一个简单的字符串的拼接
                A.id(idx)=b;
                k = message(1:n-1,:);
                eval([b,'=k']);         %这个eval函数非常不利于代码的阅读，考虑后面替换这几行代码
                message(1:n-1,:)=[];
                row_column_table = size(message);
                row_table = row_column_table(1);
            end
            %% 清楚多余变量
            clear_var = {'n','k','a'};
            clear (clear_var{:});
            clear -regexp ^del ^row;
            clear message;
            app.idEditField_4.Value = id_amount;
            app.UITable3.Data = A;
            msgbox("id查询完成","提示"); filename3 = app.EditField_8.Value;
            message = importfileasc(app,filename3);
            % importfileasc(input('请输入目标asc文件名(包含文件类型后缀）：','s'))

            %% 表格预处理、删除前四行、删除错误行、删除多余列、生成新的表格。
            % 删除前四行  注意变量的使用，不要重复了。删除行delrow。
            delrow = 1;
            a = 1;
            while a<5
                message(delrow,:)=[];
                a = a+1;
            end
            % message([1:4],:) = [];这条语句和上面功能一样，

            % 删除多余列    仅仅保留time id digit(位数) x01\x02\x03
            message.Wed = [];
            message.VarName4 = [];
            message.VarName5 = [];
            message.VarName15 = [];
            message.VarName16 = [];
            message.VarName17 = [];
            message.VarName18 = [];
            message.VarName19 = [];
            message.VarName20 = [];
            message.VarName21 = [];
            message.VarName22 = [];
            message.VarName23 = [];

            % 删除错误行  x01 = 'active'、
            del_row1 = message.x01(:) == 'active';
            %del_index1 = find(del_row1);
            message(del_row1,:) = [];

            % 删除错误行， id = 'Statistic'
            del_row2 = message.id(:) == 'Statistic:';
            %del_index2 = find(del_row2);
            message(del_row2,:) = [];

            % 删除错误行，x02 = '='
            del_row10 = message.x02(:) == '=';
            %del_index10 = find(del_row10);
            message(del_row10,:) = [];
            del_row3 = message.x02(:) == 'Length';
            del_index3 = find(del_row3);
            message.x02(del_index3) = "00";
            message.x03(del_index3) = "00";
            message.x04(del_index3) = "00";
            message.x05(del_index3) = "00";
            message.x06(del_index3) = "00";
            message.x07(del_index3) = "00";
            message.x08(del_index3) = "00";

            % 删除错误信息，并用‘00’代替 x03
            del_row4 = message.x03(:) == 'Length';
            del_index4 = find(del_row4);
            message.x03(del_index4) = "00";
            message.x04(del_index4) = "00";
            message.x05(del_index4) = "00";
            message.x06(del_index4) = "00";
            message.x07(del_index4) = "00";
            message.x08(del_index4) = "00";


            % 删除错误信息，并用‘00’代替 x04
            del_row5 = message.x04(:) == 'Length';
            del_index5 = find(del_row5);
            message.x04(del_index5) = "00";
            message.x05(del_index5) = "00";
            message.x06(del_index5) = "00";
            message.x07(del_index5) = "00";
            message.x08(del_index5) = "00";

            % 删除错误信息，并用‘00’代替 x05
            del_row6 = message.x05(:) == 'Length';
            del_index6 = find(del_row6);
            message.x05(del_index6) = "00";
            message.x06(del_index6) = "00";
            message.x07(del_index6) = "00";
            message.x08(del_index6) = "00";

            % 删除错误信息，并用‘00’代替 x06
            del_row7 = message.x06(:) == 'Length';
            del_index7 = find(del_row7);
            message.x06(del_index7) = "00";
            message.x07(del_index7) = "00";
            message.x08(del_index7) = "00";

            % 删除错误信息，并用‘00’代替 x07
            del_row8 = message.x07(:) == 'Length';
            del_index8 = find(del_row8);
            message.x07(del_index8) = "00";
            message.x08(del_index8) = "00";

            % 删除错误信息，并用‘00’代替 x08
            del_row9 = message.x08(:) == 'Length';
            del_index9 = find(del_row9);
            message.x08(del_index9) = "00";

            % 报文转换成二进制报文
            message.x01 = dec2bin(hex2dec(message.x01(:)));
            message.x02 = dec2bin(hex2dec(message.x02(:)));
            message.x03 = dec2bin(hex2dec(message.x03(:)));
            message.x04 = dec2bin(hex2dec(message.x04(:)));
            message.x05 = dec2bin(hex2dec(message.x05(:)));
            message.x06 = dec2bin(hex2dec(message.x06(:)));
            message.x07 = dec2bin(hex2dec(message.x07(:)));
            message.x08 = dec2bin(hex2dec(message.x08(:)));

            %% 对表格进行排序
            message = sortrows(message,2);   %依照id列对table进行排序

            %% 计算出来总共有多少个变量，一个数据里面id数量确认
            message1 = message;  %这个变量用于测试出这个数据里面又多少个id
            row_column_table1 = size(message1);
            row_table1 = row_column_table1(1);
            id_amount = 0;
            while row_table1 > 0 %程序退出判断当message的row为0退出循环,退出循环
                id_amount = id_amount + 1;
                %动态变量删除和生成新的表代码
                del_norm1 = message1.id(1);   % del_norm 删除选择基准变量
                p = 2;
                while message1.id(p)== del_norm1
                    p = p+1;
                    if p > row_table1
                        break
                    end
                end
                message1(1:p-1,:)=[];
                row_column_table1 = size(message1);
                row_table1 = row_column_table1(1);
            end
            %% 清除测试中用到的多余变量
            clear message1;
            clear p;
            clear row_column_table1;
            clear row_table1;

            %% 对排序后的表格进行提取依照id创建多个子表格
            row_column_table = size(message);
            row_table = row_column_table(1);
            A = table('Size',[id_amount 1],'VariableNames',{'id'},'VariableTypes',{'string'}) ;
            idx = 0;
            while idx < id_amount    %程序退出判断当message的row为0退出循环,退出循环
                %动态变量删除和生成新的表代码
                idx = idx +1;
                del_norm = message.id(1);   % del_norm 删除选择基准变量
                n = 2;
                while message.id(n)== del_norm
                    n = n+1;
                    if n > row_table
                        break
                    end
                end
                b = ['message_',char(del_norm)];%这里就是一个简单的字符串的拼接
                A.id(idx)=b;
                k = message(1:n-1,:);
                eval([b,'=k']);         %这个eval函数非常不利于代码的阅读，考虑后面替换这几行代码
                message(1:n-1,:)=[];
                row_column_table = size(message);
                row_table = row_column_table(1);
            end
            %% 清楚多余变量
            clear_var = {'n','k','a'};
            clear (clear_var{:});
            clear -regexp ^del ^row;
            clear message;
            app.idEditField_4.Value = id_amount;
            app.UITable3.Data = A;
            msgbox("id查询完成","提示");
        end
        end

        % Callback function
        function Button_8Pushed(app, event)
            save_dir2 = uigetdir();
            save_dir_3 = [save_dir2,'\'];
            app.EditField_9.Value = save_dir_3;
            msgbox("存储路径选择完成","提示");
        end

        % Callback function
        function Button_9Pushed(app, event)

        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            [file3,path3] = uigetfile('.asc','选择文件');
            filename3 = [path3,file3];
            app.EditField_10.Value = filename3;
            msgbox("文件路径选择成功","提示");
        end

        % Button pushed function: IDButton_2
        function IDButton_2Pushed(app, event)
            filename4 = app.EditField_10.Value;
            app.ID = importfileasc(app,filename4);
            delrow = 1;
            a = 1;
            while a<5
                app.ID(delrow,:)=[];
                a = a+1;
            end

            % 删除多余列    仅仅保留time id digit(位数) x01\x02\x03
            app.ID.Wed = [];
            app.ID.VarName4 = [];
            app.ID.VarName5 = [];
            app.ID.VarName15 = [];
            app.ID.VarName16 = [];
            app.ID.VarName17 = [];
            app.ID.VarName18 = [];
            app.ID.VarName19 = [];
            app.ID.VarName20 = [];
            app.ID.VarName21 = [];
            app.ID.VarName22 = [];
            app.ID.VarName23 = [];

            % 删除错误行  x01 = 'active'、
            del_row1 = app.ID.x01(:) == 'active';
            %del_index1 = find(del_row1);
            app.ID(del_row1,:) = [];

            % 删除错误行， id = 'Statistic'
            del_row2 = app.ID.id(:) == 'Statistic:';
            %del_index2 = find(del_row2);
            app.ID(del_row2,:) = [];

            % 删除错误行，x02 = '='
            del_row10 = app.ID.x02(:) == '=';
            %del_index10 = find(del_row10);
            app.ID(del_row10,:) = [];

            %% 删除错误行信息
            % 删除错误信息，并用‘00’代替 x02
            del_row3 = app.ID.x02(:) == 'Length';
            del_index3 = find(del_row3);
            app.ID.x02(del_index3) = "00";
            app.ID.x03(del_index3) = "00";
            app.ID.x04(del_index3) = "00";
            app.ID.x05(del_index3) = "00";
            app.ID.x06(del_index3) = "00";
            app.ID.x07(del_index3) = "00";
            app.ID.x08(del_index3) = "00";

            % 删除错误信息，并用‘00’代替 x03
            del_row4 = app.ID.x03(:) == 'Length';
            del_index4 = find(del_row4);
            app.ID.x03(del_index4) = "00";
            app.ID.x04(del_index4) = "00";
            app.ID.x05(del_index4) = "00";
            app.ID.x06(del_index4) = "00";
            app.ID.x07(del_index4) = "00";
            app.ID.x08(del_index4) = "00";


            % 删除错误信息，并用‘00’代替 x04
            del_row5 = app.ID.x04(:) == 'Length';
            del_index5 = find(del_row5);
            app.ID.x04(del_index5) = "00";
            app.ID.x05(del_index5) = "00";
            app.ID.x06(del_index5) = "00";
            app.ID.x07(del_index5) = "00";
            app.ID.x08(del_index5) = "00";

            % 删除错误信息，并用‘00’代替 x05
            del_row6 = app.ID.x05(:) == 'Length';
            del_index6 = find(del_row6);
            app.ID.x05(del_index6) = "00";
            app.ID.x06(del_index6) = "00";
            app.ID.x07(del_index6) = "00";
            app.ID.x08(del_index6) = "00";

            % 删除错误信息，并用‘00’代替 x06
            del_row7 = app.ID.x06(:) == 'Length';
            del_index7 = find(del_row7);
            app.ID.x06(del_index7) = "00";
            app.ID.x07(del_index7) = "00";
            app.ID.x08(del_index7) = "00";

            % 删除错误信息，并用‘00’代替 x07
            del_row8 = app.ID.x07(:) == 'Length';
            del_index8 = find(del_row8);
            app.ID.x07(del_index8) = "00";
            app.ID.x08(del_index8) = "00";

            % 删除错误信息，并用‘00’代替 x08
            del_row9 = app.ID.x08(:) == 'Length';
            del_index9 = find(del_row9);
            app.ID.x08(del_index9) = "00";

            % 报文转换成二进制报文
            app.ID.x01 = dec2bin(hex2dec(app.ID.x01(:)));
            app.ID.x02 = dec2bin(hex2dec(app.ID.x02(:)));
            app.ID.x03 = dec2bin(hex2dec(app.ID.x03(:)));
            app.ID.x04 = dec2bin(hex2dec(app.ID.x04(:)));
            app.ID.x05 = dec2bin(hex2dec(app.ID.x05(:)));
            app.ID.x06 = dec2bin(hex2dec(app.ID.x06(:)));
            app.ID.x07 = dec2bin(hex2dec(app.ID.x07(:)));
            app.ID.x08 = dec2bin(hex2dec(app.ID.x08(:)));

            app.ID = sortrows(app.ID,2);   %依照id列对table进行排序

            %% 计算出来总共有多少个变量，一个数据里面id数量确认
            ID1 = app.ID;  %这个变量用于测试出这个数据里面又多少个id
            row_column_table1 = size(ID1);
            row_table1 = row_column_table1(1);
            app.CAN_id_amount = 0;
            while row_table1 > 0 %程序退出判断当message的row为0退出循环,退出循环
                app.CAN_id_amount = app.CAN_id_amount + 1;
                %动态变量删除和生成新的表代码
                del_norm1 = ID1.id(1);   % del_norm 删除选择基准变量
                p = 2;
                while ID1.id(p)== del_norm1
                    p = p+1;
                    if p > row_table1
                        break
                    end
                end
                ID1(1:p-1,:)=[];
                row_column_table1 = size(ID1);
                row_table1 = row_column_table1(1);
            end
            %% 清除测试中用到的多余变量

            %% 对排序后的表格进行提取依照id创建多个子表格
            row_column_table = size(app.ID);
            row_table = row_column_table(1);
            app.CAN_A = table('Size',[app.CAN_id_amount 1],'VariableNames',{'id'},'VariableTypes',{'string'}) ;
            idx = 0;
            while idx < app.CAN_id_amount    %程序退出判断当message的row为0退出循环,退出循环
                %动态变量删除和生成新的表代码
                idx = idx +1;
                del_norm = app.ID.id(1);   % del_norm 删除选择基准变量
                n = 2;
                while app.ID.id(n)== del_norm
                    n = n+1;
                    if n > row_table
                        break
                    end
                end
                b = ['ID_',char(del_norm)];%这里就是一个简单的字符串的拼接
                app.CAN_A.id(idx)=b;
                k = app.ID(1:n-1,:);
                app.CAN_ID_cell.(b) = k;        %这个eval函数非常不利于代码的阅读，考虑后面替换这几行代码
                app.ID(1:n-1,:)=[];
                row_column_table = size(app.ID);
                row_table = row_column_table(1);
            end
            %% 清楚多余变量
            app.IDEditField_3.Value = app.CAN_id_amount;
            app.CAN_A.index = [1:app.CAN_id_amount]';
            app.UITable4.Data = app.CAN_A;
            msgbox("id查询完成","提示");
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            save_dir3 = uigetdir();
            save_dir_4 = [save_dir3,'\'];
            app.EditField_11.Value = save_dir_4;
            msgbox("存储路径选择完成","提示");
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)

            id_seq = str2num(app.IDEditField_5.Value);
            amount_seq = str2num(app.EditField_19.Value);
            opt_int_moto = app.DropDown_6.Value;
            fig_str4 = app.EditField_11.Value;
            signal_judge = app.DropDown_8.Value;
            for i = id_seq
                P = app.CAN_A.id(i);
                opt_int_id = app.CAN_ID_cell.(P);
                fig_id = opt_int_id.id(1);
                table_bl_dbc = table([7 6 5 4 3 2 1 0 15 14 13 12 11 10 9 8 23 22 21 20 19 18 17 16 31 30 29 28 27 26 25 24 39 38 37 36 35 34 33 32 47 46 45 44 43 42 41 40 55 54 53 52 51 50 49 48 63 62 61 60 59 58 57 56 ]',[1:64]','VariableNames',{'dbc_Sta','bl_sta',});
                %switch语句
                switch opt_int_moto
                    case 'Intel'
                        opt_int_id.x09= [opt_int_id.x08,opt_int_id.x07,opt_int_id.x06,opt_int_id.x05,opt_int_id.x04,opt_int_id.x03,opt_int_id.x02,opt_int_id.x01];
                        for range_bit = amount_seq    %       start_bit = 64-input('请输入起始位（范围0-63）：');
                            for start_bit = range_bit:64
                                a = opt_int_id{:,12};
                                b = a(:,start_bit-range_bit+1:start_bit);
                                size_b =size(b);
                                ku = size_b(2);
                                size_b_2 = size_b(1,1);
                                q = 1;
                                while q < size_b_2
                                    judge_b = strcmp(b(q,:),b(q+1,:));
                                    q = q+1;
                                    if judge_b == 0
                                        x = opt_int_id.time;
                                        if signal_judge == "unsigned"
                                            y = bin2dec(b);
                                        elseif signal_judge == "signed"
                                            signed_bit = b(:,1);
                                            for p = 1:size_b_2
                                                if signed_bit(p) == '0'
                                                    opt_int_id.x10(p,:) = bin2dec(b(p,:));
                                                elseif signed_bit(p) == '1'
                                                    opt_int_id.x10(p,:) = (2^ku - bin2dec(b(p,:)))*-1;
                                                else
                                                    return
                                                end
                                            end
                                            y = opt_int_id.x10;
                                        end
                                        plot(app.UIAxes,x,y);
                                        app.UIAxes.Title.String = sprintf('ID是%s；起始位是%s；信号位数是%s',fig_id,num2str(64-start_bit),num2str(range_bit));
                                        exportgraphics(app.UIAxes,[fig_str4,sprintf('ID%s_%s_%s',fig_id,num2str(64-start_bit),num2str(range_bit)),'.jpg']);
                                        close;
                                        break
                                    elseif q == size_b_2
                                        break
                                    end
                                end
                            end
                        end
                    case 'Motorola'
                        opt_int_id.x09= [opt_int_id.x01,opt_int_id.x02,opt_int_id.x03,opt_int_id.x04,opt_int_id.x05,opt_int_id.x06,opt_int_id.x07,opt_int_id.x08];
                        for range_bit = amount_seq
                            for start_bit_m = range_bit:64
                                bl_row = table_bl_dbc.bl_sta(:) == start_bit_m;
                                bl_index = find(bl_row);
                                dbc_num = table_bl_dbc.dbc_Sta(bl_index);
                                a = opt_int_id{:,12};
                                b = a(:,start_bit_m-range_bit+1:start_bit_m);
                                size_b =size(b);
                                ku = size_b(2);
                                size_b_2 = size_b(1,1);
                                q = 1;
                                while q < size_b_2
                                    judge_b = strcmp(b(q,:),b(q+1,:));
                                    q = q+1;
                                    if judge_b == 0
                                        x = opt_int_id.time;
                                        if signal_judge == "unsigned"
                                            y = bin2dec(b);
                                        elseif signal_judge == "signed"
                                            signed_bit = b(:,1);
                                            for p = 1:size_b_2
                                                if signed_bit(p) == '0'
                                                    opt_int_id.x10(p,:) = bin2dec(b(p,:));
                                                elseif signed_bit(p) == '1'
                                                    opt_int_id.x10(p,:) = (2^ku - bin2dec(b(p,:)))*-1;
                                                else
                                                    return
                                                end
                                            end
                                            y = opt_int_id.x10;
                                        end
                                        plot(app.UIAxes,x,y);
                                        app.UIAxes.Title.String = sprintf('ID是%s；起始位是%s；信号位数是%s',fig_id,num2str(dbc_num),num2str(range_bit));
                                        exportgraphics(app.UIAxes,[fig_str4,sprintf('ID%s_%s_%s',fig_id,num2str(dbc_num),num2str(range_bit)),'.jpg']);
                                        close;
                                        break
                                    elseif q == size_b_2
                                        break
                                    end
                                end
                            end
                        end
                    otherwise
                        disp('你的输入不符合要求，请按要求输入：')
                end
            end
            msgbox("自定义遍历完成","提示");
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            file_path =  uigetdir();
            file_path_1 = [file_path,'\'];
            app.EditField_12.Value = file_path_1;
            msgbox("文件夹选择完成","提示");
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            img_path_list = dir(strcat(app.EditField_12.Value,'*jpg'));%获取该文件夹中所有.jpg格式的图像
            img_num = length(img_path_list);%获取图像总数
            h = waitbar(0.1,"正在处理");
            if img_num > 0 %有满足条件的图像
                for pn = 1:img_num %逐一读取图像
                    image_name = img_path_list(pn).name;% 图像名
                    a =  imread(strcat(app.EditField_12.Value,image_name));%读取图像
                    a = double(a);
                    R = a(:,:,1);
                    G = a(:,:,2);
                    B = a(:,:,3);
                    sz = size(a);
                    num = 0;
                    for i = 1:sz(1)*sz(2)
                        if  R(i) == 255 && G(i) == 255 && B(i) == 255
                            num = num+1;
                        end
                    end
                    pw = num/(sz(1)*sz(2));
                    if pw < 0.71
                        delete(strcat(app.EditField_12.Value,image_name))
                    end
                    str1 = ['图片处理中...',num2str(100*pn/img_num),'%'];
                    waitbar (pn/img_num,h,str1);
                end
            end
            msgbox("删除杂乱信息完成","提示");
        end

        % Button pushed function: CADFDButton
        function CADFDButtonPushed(app, event)
            [file4,path4] = uigetfile('.asc','选择文件');
            filename4 = [path4,file4];
            app.EditField_13.Value = filename4;
            msgbox("文件路径选择成功","提示");
        end

        % Button pushed function: IDButton
        function IDButtonPushed(app, event)

        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            save_dir4 = uigetdir();
            save_dir_5 = [save_dir4,'\'];
            app.EditField_14.Value = save_dir_5;
            msgbox("存储路径选择完成","提示");
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            value = app.DropDown_6.Value;
        end

        % Drop down opening function: DropDown_6
        function DropDown_6Opening(app, event)
            value = app.DropDown_6.Value;
        end

        % Callback function
        function DropDown_3Opening(app, event)
            value = app.DropDown_3.Value;
        end

        % Callback function
        function DropDownOpening(app, event)
            value = app.DropDown.Value;
        end

        % Callback function
        function DropDown_2Opening(app, event)
            value = app.DropDown_2.Value;  
        end

        % Drop down opening function: DropDown_5
        function DropDown_5Opening(app, event)
            value = app.DropDown_5.Value;
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            %% CANFD的自定义遍历功能
            save_dir_2 = app.EditField_14.Value;
            id_seq = str2num(app.IDEditField_6.Value);
            amount_seq = str2num(app.EditField_20.Value);
            opt_int_moto = app.DropDown_5.Value;
            signal_judge = app.DropDown_9.Value;
            for i = id_seq
                P = app.CANFD_A.id(i);
                opt_int_id = app.CANFD_ID_Cell.(P);
                endbit = opt_int_id.Digit(1);
                fig_id = opt_int_id.ID(1);
                table_bl_dbc = table([7 6 5 4 3 2 1 0 15 14 13 12 11 10 9 8 23 22 21 20 19 18 17 16 31 30 29 28 27 26 25 24 39 38 37 36 35 34 33 32 47 46 45 44 43 42 41 40 55 54 53 52 51 50 49 48 63 62 61 60 59 58 57 56 ...
                    71 70 69 68 67 66 65 64 79 78 77 76 75 74 73 72 87 86 85 84 83 82 81 80 95 94 93 92 91 90 89 88 103 102 101 100 99 98 97 96 111 110 109 108 107 106 105 104 119 118 117 116 115 114 113 112 127 126 125 124 123 122 121 120 ...
                    135 134 133 132 131 130 129 128 143 142 141 140 139 138 137 136 151 150 149 148 147 146 145 144 159 158 157 156 155 154 153 152 167 166 165 164 163 162 161 160 175 174 173 172 171 170 169 168 183 182 181 180 179 178 177 176 191 190 189 188 187 186 185 184 ...
                    199 198 197 196 195 194 193 192 207 206 205 204 203 202 201 200 215 214 213 212 211 210 209 208 223 222 221 220 219 218 217 216 231 230 229 228 227 226 225 224 239 238 237 236 235 234 233 232 247 246 245 244 243 242 241 240 255 254 253 252 251 250 249 248 ...
                    263 262 261 260 259 258 257 256 271 270 269 268 267 266 265 264 279 278 277 276 275 274 273 272 287 286 285 284 283 282 281 280 295 294 293 292 291 290 289 288 303 302 301 300 299 298 297 296 311 310 309 308 307 306 305 304 319 318 317 316 315 314 313 312 ...
                    327 326 325 324 323 322 321 320 335 334 333 332 331 330 329 328 343 342 341 340 339 338 337 336 351 350 349 348 347 346 345 344 359 358 357 356 355 354 353 352 367 366 365 364 363 362 361 360 375 374 373 372 371 370 369 368 383 382 381 380 379 378 377 376 ...
                    391 390 389 388 387 386 385 384 399 398 397 396 395 394 393 392 407 406 405 404 403 402 401 400 415 414 413 412 411 410 409 408 423 422 421 420 419 418 417 416 431 430 429 428 427 426 425 424 439 438 437 436 435 434 433 432 447 446 445 444 443 442 441 440 ...
                    455 454 453 452 451 450 449 448 463 462 461 460 459 458 457 456 471 470 469 468 467 466 465 464 479 478 477 476 475 474 473 472 487 486 485 484 483 482 481 480 495 494 493 492 491 490 489 488 503 502 501 500 499 498 497 496 511 510 509 508 507 506 505 504 ...
                    ]',[1:512]','VariableNames',{'dbc_Sta','bl_sta',});
                %switch语句
                switch opt_int_moto
                    case "Intel"
                        opt_int_id.x09= [opt_int_id.VarName73,opt_int_id.VarName72,opt_int_id.VarName71,opt_int_id.VarName70,opt_int_id.VarName69,opt_int_id.VarName68,opt_int_id.VarName67,opt_int_id.VarName66,opt_int_id.VarName65,opt_int_id.VarName64,opt_int_id.VarName63,opt_int_id.VarName62,opt_int_id.VarName61,opt_int_id.VarName60,opt_int_id.VarName59,opt_int_id.VarName58,opt_int_id.VarName57,opt_int_id.VarName56,opt_int_id.VarName55,opt_int_id.VarName54,opt_int_id.VarName53,opt_int_id.VarName52,opt_int_id.VarName51,opt_int_id.VarName50,opt_int_id.VarName49,opt_int_id.VarName48,opt_int_id.VarName47,opt_int_id.VarName46,opt_int_id.VarName45,opt_int_id.VarName44,opt_int_id.VarName43,opt_int_id.VarName42,opt_int_id.VarName41,opt_int_id.VarName40,opt_int_id.VarName39,opt_int_id.VarName38,opt_int_id.VarName37,opt_int_id.VarName36,opt_int_id.VarName35,opt_int_id.VarName34,opt_int_id.VarName33,opt_int_id.VarName32,opt_int_id.VarName31,opt_int_id.VarName30,opt_int_id.VarName29,opt_int_id.VarName28,opt_int_id.VarName27,opt_int_id.VarName26,opt_int_id.VarName25,opt_int_id.VarName24,opt_int_id.VarName23,opt_int_id.VarName22,opt_int_id.VarName21,opt_int_id.VarName20,opt_int_id.VarName19,opt_int_id.VarName18,opt_int_id.VarName17,opt_int_id.VarName16,opt_int_id.VarName15,opt_int_id.VarName14,opt_int_id.VarName13,opt_int_id.VarName12,opt_int_id.VarName11,opt_int_id.VarName10];
                        for range_bit = amount_seq    %       start_bit = 64-input('请输入起始位（范围0-63）：');
                            for start_bit = (64-endbit)*8+range_bit:512
                                a = opt_int_id{:,68};
                                b = a(:,start_bit-range_bit+1:start_bit);
                                size_b =size(b);
                                ku = size_b(2);
                                size_b_2 = size_b(1,1);
                                q = 1;
                                while q < size_b_2
                                    judge_b = strcmp(b(q,:),b(q+1,:));
                                    q = q+1;
                                    if judge_b == 0
                                        x = opt_int_id.date;
                                        if signal_judge == "unsigned"
                                            y = bin2dec(b);
                                        elseif signal_judge == "signed"
                                            signed_bit = b(:,1);
                                            for p = 1:size_b_2
                                                if signed_bit(p) == '0'
                                                    opt_int_id.x10(p,:) = bin2dec(b(p,:));
                                                elseif signed_bit(p) == '1'
                                                    opt_int_id.x10(p,:) = (2^ku - bin2dec(b(p,:)))*-1;
                                                else
                                                    return
                                                end
                                            end
                                            y = opt_int_id.x10;
                                        end
                                        plot(app.UIAxes,x,y);
                                        app.UIAxes.Title.String = sprintf('ID是%s；起始位是%s；信号位数是%s',fig_id,num2str(512-start_bit),num2str(range_bit));
                                        exportgraphics(app.UIAxes,[save_dir_2,sprintf('ID%s_%s_%s',fig_id,num2str(512-start_bit),num2str(range_bit)),'.jpg']);
                                        close;
                                        break
                                    elseif q == size_b_2
                                        break
                                    end
                                end
                            end
                        end
                    case "Motorola"
                        opt_int_id.x09= [opt_int_id.VarName10,opt_int_id.VarName11,opt_int_id.VarName12,opt_int_id.VarName13,opt_int_id.VarName14,opt_int_id.VarName15,opt_int_id.VarName16,opt_int_id.VarName17,opt_int_id.VarName18,opt_int_id.VarName19,opt_int_id.VarName20,opt_int_id.VarName21,opt_int_id.VarName22,opt_int_id.VarName23,opt_int_id.VarName24,opt_int_id.VarName25,opt_int_id.VarName26,opt_int_id.VarName27,opt_int_id.VarName28,opt_int_id.VarName29,opt_int_id.VarName30,opt_int_id.VarName31,opt_int_id.VarName32,opt_int_id.VarName33,opt_int_id.VarName34,opt_int_id.VarName35,opt_int_id.VarName36,opt_int_id.VarName37,opt_int_id.VarName38,opt_int_id.VarName39,opt_int_id.VarName40,opt_int_id.VarName41,opt_int_id.VarName42,opt_int_id.VarName43,opt_int_id.VarName44,opt_int_id.VarName45,opt_int_id.VarName46,opt_int_id.VarName47,opt_int_id.VarName48,opt_int_id.VarName49,opt_int_id.VarName50,opt_int_id.VarName51,opt_int_id.VarName52,opt_int_id.VarName53,opt_int_id.VarName54,opt_int_id.VarName55,opt_int_id.VarName56,opt_int_id.VarName57,opt_int_id.VarName58,opt_int_id.VarName59,opt_int_id.VarName60,opt_int_id.VarName61,opt_int_id.VarName62,opt_int_id.VarName63,opt_int_id.VarName64,opt_int_id.VarName65,opt_int_id.VarName66,opt_int_id.VarName67,opt_int_id.VarName68,opt_int_id.VarName69,opt_int_id.VarName70,opt_int_id.VarName71,opt_int_id.VarName72,opt_int_id.VarName73];
                        for range_bit = amount_seq
                            for start_bit_m = range_bit:endbit*8
                                bl_row = table_bl_dbc.bl_sta(:) == start_bit_m;
                                bl_index = find(bl_row);
                                dbc_num = table_bl_dbc.dbc_Sta(bl_index);
                                a = opt_int_id{:,68};
                                b = a(:,start_bit_m-range_bit+1:start_bit_m);
                                size_b =size(b);
                                ku = size_b(2);
                                size_b_2 = size_b(1,1);
                                q = 1;
                                while q < size_b_2
                                    judge_b = strcmp(b(q,:),b(q+1,:));
                                    q = q+1;
                                    if judge_b == 0
                                        x = opt_int_id.date;
                                        if signal_judge == "unsigned"
                                            y = bin2dec(b);
                                        elseif signal_judge == "signed"
                                            signed_bit = b(:,1);
                                            for p = 1:size_b_2
                                                if signed_bit(p) == '0'
                                                    opt_int_id.x10(p,:) = bin2dec(b(p,:));
                                                elseif signed_bit(p) == '1'
                                                    opt_int_id.x10(p,:) = (2^ku - bin2dec(b(p,:)))*-1;
                                                else
                                                    return
                                                end
                                            end
                                            y = opt_int_id.x10;
                                        end
                                        plot(app.UIAxes,x,y);
                                        app.UIAxes.Title.String = sprintf('ID是%s；起始位是%s；信号位数是%s',fig_id,num2str(dbc_num),num2str(range_bit));
                                        exportgraphics(app.UIAxes,[save_dir_2,sprintf('ID%s_%s_%s',fig_id,num2str(dbc_num),num2str(range_bit)),'.jpg']);
                                        close;
                                        break
                                    elseif q == size_b_2
                                        break
                                    end
                                end
                            end
                        end
                    otherwise
                        disp('你的输入不符合要求，请按要求输入：')
                end
            end
            msgbox("自定义遍历完成","提示");
        end

        % Callback function
        function CANTab_2SizeChanged(app, event)
            position = app.IDTab.Position;
            
        end

        % Callback function
        function DropDown_7Opening(app, event)
            value = app.DropDown_7.Value;
        end

        % Drop down opening function: DropDown_8
        function DropDown_8Opening(app, event)
            value = app.DropDown_8.Value;
        end

        % Drop down opening function: DropDown_9
        function DropDown_9Opening(app, event)
            value = app.DropDown_9.Value;
        end

        % Callback function
        function Button_17Pushed(app, event)
            msgbox('这是一个信号解析的小程序','功能使用说明','help');
        end

        % Callback function
        function CANalyzerDropDownOpening(app, event)
            value = app.CANalyzerDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '菜单';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '退出';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '关于';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CANTab = uitab(app.TabGroup);
            app.CANTab.Title = 'CAN信号解析';
            app.Button_10 = uibutton(app.CANTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '文件导入';
            app.Label_16 = uilabel(app.CANTab);
            app.Label_16.Text = '文件路径';
            app.EditField_10 = uieditfield(app.CANTab, 'text');
            app.IDButton_2 = uibutton(app.CANTab, 'push');
            app.IDButton_2.ButtonPushedFcn = createCallbackFcn(app, @IDButton_2Pushed, true);
            app.IDButton_2.Text = 'ID查询';
            app.IDEditField_3Label = uilabel(app.CANTab);
            app.IDEditField_3Label.Text = 'ID个数';
            app.IDEditField_3 = uieditfield(app.CANTab, 'numeric');
            app.UITable4 = uitable(app.CANTab);
            app.UITable4.ColumnName = {'ID详情'; 'ID序号'};
            app.UITable4.RowName = {};
            app.Button_11 = uibutton(app.CANTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '存储位置';
            app.Label_17 = uilabel(app.CANTab);
            app.Label_17.Text = '存储路径';
            app.EditField_11 = uieditfield(app.CANTab, 'text');
            app.Button_12 = uibutton(app.CANTab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '自定义遍历';
            app.Label_22 = uilabel(app.CANTab);
            app.Label_22.Text = '信号格式';
            app.DropDown_6 = uidropdown(app.CANTab);
            app.DropDown_6.Items = {'Intel', 'Motorola'};
            app.DropDown_6.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_6Opening, true);
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Value = 'Intel';
            app.Label_28 = uilabel(app.CANTab);
            app.Label_28.Text = '有无符号';
            app.DropDown_8 = uidropdown(app.CANTab);
            app.DropDown_8.Items = {'unsigned', 'signed'};
            app.DropDown_8.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_8Opening, true);
            app.DropDown_8.Value = 'unsigned';
            app.IDEditField_5Label = uilabel(app.CANTab);
            app.IDEditField_5Label.Text = 'ID选择';
            app.IDEditField_5 = uieditfield(app.CANTab, 'text');
            app.Label_31 = uilabel(app.CANTab);
            app.Label_31.Text = '位数选择';
            app.EditField_19 = uieditfield(app.CANTab, 'text');
            app.CANFDTab = uitab(app.TabGroup);
            app.CANFDTab.Title = 'CANFD信号解析';
            app.CADFDButton = uibutton(app.CANFDTab, 'push');
            app.CADFDButton.ButtonPushedFcn = createCallbackFcn(app, @CADFDButtonPushed, true);
            app.CADFDButton.Text = 'CADFD文件导入';
            app.Label_20 = uilabel(app.CANFDTab);
            app.Label_20.Text = '文件路径';
            app.EditField_13 = uieditfield(app.CANFDTab, 'text');
            app.IDButton = uibutton(app.CANFDTab, 'push');
            app.IDButton.ButtonPushedFcn = createCallbackFcn(app, @IDButtonPushed, true);
            app.IDButton.Text = 'ID查询';
            app.IDEditFieldLabel = uilabel(app.CANFDTab);
            app.IDEditFieldLabel.Text = 'ID个数';
            app.IDEditField = uieditfield(app.CANFDTab, 'numeric');
            app.UITable5 = uitable(app.CANFDTab);
            app.UITable5.ColumnName = {'ID详情'; 'ID序号'};
            app.UITable5.RowName = {};
            app.Button_15 = uibutton(app.CANFDTab, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '存储位置';
            app.EditField_14Label = uilabel(app.CANFDTab);
            app.EditField_14Label.Text = '存储路径';
            app.EditField_14 = uieditfield(app.CANFDTab, 'text');
            app.Button_16 = uibutton(app.CANFDTab, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '自定义遍历';
            app.Label_21 = uilabel(app.CANFDTab);
            app.Label_21.Text = '信号格式';
            app.DropDown_5 = uidropdown(app.CANFDTab);
            app.DropDown_5.Items = {'Intel', 'Motorola'};
            app.DropDown_5.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_5Opening, true);
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = 'Intel';
            app.Label_29 = uilabel(app.CANFDTab);
            app.Label_29.Text = '有无符号';
            app.DropDown_9 = uidropdown(app.CANFDTab);
            app.DropDown_9.Items = {'unsigned', 'signed'};
            app.DropDown_9.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_9Opening, true);
            app.DropDown_9.Value = 'unsigned';
            app.IDLabel_2 = uilabel(app.CANFDTab);
            app.IDLabel_2.Text = 'ID 选 择';
            app.IDEditField_6 = uieditfield(app.CANFDTab, 'text');
            app.Label_30 = uilabel(app.CANFDTab);
            app.Label_30.Text = '位数选择';
            app.EditField_20 = uieditfield(app.CANFDTab, 'text');
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '删除杂乱信息';
            app.Button_13 = uibutton(app.Tab_5, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '删除杂乱曲线';
            app.Button_14 = uibutton(app.Tab_5, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '待删除文件文件夹';
            app.Label_19 = uilabel(app.Tab_5);
            app.Label_19.Text = '文件夹地址';
            app.EditField_12 = uieditfield(app.Tab_5, 'text');
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '实时观看区';
            app.UIAxes = uiaxes(app.Tab_3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'                 使用步骤'; '1、文件导入：导入ASCII格式的原报文'; '2、ID查询：查询导入报文包含的ID信息'; '3、存储位置：确定程序绘制的曲线图片存储位置'; '4、自定义遍历：选择和填写遍历配置信息（信号格式、有无符号、ID选择、位数选择）。其中ID选择填写ID详情列表对应的ID序号，位数选择的数值范围为1-16,数字中间用空格隔开，如果要连续选择请使用“：”，比如1:16。配置好后按自定义遍历按钮开始遍历'; '5、删除杂乱信息：遍历完成后，选择步骤3确定的文件夹，删除杂乱曲线。'};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = '威马头像框.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AUTOSAS_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end